classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NameEditFieldLabel    matlab.ui.control.Label
        ImageFileL            matlab.ui.control.EditField
        NameEditField_2Label  matlab.ui.control.Label
        ImageFileR            matlab.ui.control.EditField
        Start                 matlab.ui.control.Button
        UIAxesL               matlab.ui.control.UIAxes
        UIAxesR               matlab.ui.control.UIAxes
        FramesLabel           matlab.ui.control.Label
        FramesSlider          matlab.ui.control.Slider
        Increment             matlab.ui.control.Button
        DisabledButton        matlab.ui.control.Button
        CalculateButton       matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
       ImageR
       ImageL
       panim
       imDat   
       rS
       tform
       panorama
    %   totims
       prog
       WB
       lP
       lW
       rP
    end
    
    methods (Access = public)
        
        function getResults(app, pairL, pairR)
            app.lP = pairL;
            app.rP = pairR;
            app.CalculateButtonPushed;
        end
    end
    
    methods (Access = private)
        
        function results = func2()
            load pts.dat;  
            n=size(pts,1);

            imshow(i)
            axis on 
            hold on
for ii=1:n
    
    plot(pts(ii,1),pts(ii,2),'r+')
end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ImageFileL, ImageFileR
        function NameEditFieldValueChanged(app, event)
     
            if(event.Source == app.ImageFileL)
                value = app.ImageFileL.Value;
                app.ImageR = imread(value);
      
                imshow(app.ImageR, 'Parent',app.UIAxesL);
            elseif (event.Source == app.ImageFileR)
                value = app.ImageFileR.Value;
                app.ImageL = imread(value);
                imshow(app.ImageL, 'Parent',app.UIAxesR);
            end
        end

        % Button pushed function: Start
        function StartPushed(app, event)
            app.WB = waitbar(0, 'Loading');
            waitbar(0.1, app.WB, 'Loading');
            if(size(app.ImageL) == 0)
                app.ImageR = imread('4.png');
                app.ImageL = imread('3.png');
                imshow(app.ImageR, 'Parent',app.UIAxesL);
                imshow(app.ImageL, 'Parent',app.UIAxesR);
            end
            
            waitbar(0.1, app.WB, 'Loading');;
            Il = rgb2gray(app.ImageL);
            Ir = rgb2gray(app.ImageR);
            points = detectSURFFeatures(Il);    
            [features, points] = extractFeatures(Il, points);
            waitbar(0.2, app.WB, 'Loading');;
            points2 = detectSURFFeatures(Ir, 'MetricThreshold',3000, 'NumOctaves', 2);
            [features2, points2] = extractFeatures(Ir, points2);
            
            ind = matchFeatures(features, features2, 'Unique', true);
            
            waitbar(0.2, app.WB, 'Loading');;
            app.lP = points(ind(:, 1), :);
            app.rP = points2(ind(:, 2), :);
            app.lP = app.lP.Location;
            app.rP = app.rP.Location;

            waitbar(0.3, app.WB, 'Loading');;
            app.CalculateButtonPushed;
            %close(app.WB);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
         
    
        end

        % Callback function: FramesSlider, FramesSlider
        function SliderValueChanged(app, event)
            value = app.FramesSlider.Value;
            value=round(value);
       
          %  imshowpair(app.imDat{value}, app.imSpace(value), app.ImageR, app.rS, 'blend');
            %imshow(app.totims{min(value, app.frames)});
        end

        % Button pushed function: Increment
        function IncrementPushed(app, event)
            app.FramesSlider.Value = min(app.FramesSlider.Value+1.5, 99);
            app.SliderValueChanged;
        end

        % Button pushed function: DisabledButton
        function DisabledButtonPushed(app, event)
%             mat = tform.T;
%             incr = zeros(3,3, app.frames);
%             
%             orig = [1 0 0;0 1 0;length(app.ImageR)*1.3 0 1];
%             
%             for i=1:3
%                 for j=1:2
%                     incr(i, j, :) = linspace(orig(i, j), mat(i, j), app.frames);
%                 end
%             end
%             
%             incr(3, 3, :) = 1;
%             a0 = incr(:, :, 2:end);
%             incr=a0;
%             a0=affine2d;
%            
%         
%             app.imSpace = imref2d.empty;
%             
%             app.frames=app.frames-1;
% 
%             for i=1:app.frames
%                 fprintf("%i   ", i);
%                 a0(:, :, i) = affine2d(incr(:, :, i));
%                 [im, imS] = imwarp(app.ImageL, lS, a0(:, :, i), 'OutputView', panim);
%                 app.imSpace(i) = imS;
%                 app.imDat{i} = im;
%                 temp = imfuse(app.imDat{i}, app.imSpace(i), app.ImageR, app.rS, 'blend', 'Scaling', 'joint');
%                 app.totims{i} = uint8(zeros(len, len, 3));
%                 app.totims{i}(1:size(temp, 1), 1:size(temp, 2), :) = temp;
%                 
%                % temp= imresize(app.totims{i}, [length(app.ImageL)+length(app.ImageR) length(app.ImageL)+length(app.ImageR)]);
%             end
%                video=VideoWriter('test');
%                open(video);
%                for value = 1:app.frames
%                    
%                
%                    writeVideo(video, app.totims{value});
%                    fprintf("%i", value);
%                end
%                
%                for i = 1:50
%                    writeVideo(video, app.totims{app.frames});
%                end
%                close(video);
               
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)


            lP = app.lP;
            rP = app.rP;
            waitbar(0., app.WB, 'Loading');;
            lS = imref2d(size(app.ImageL));
            app.rS = imref2d(size(app.ImageR));

            tform = estimateGeometricTransform(SURFPoints(lP), SURFPoints(rP),'similarity', 'Confidence', 99.99, 'MaxNumTrials', 2000);
            
            
            waitbar(0.4, app.WB, 'Loading');
            
            [xlim, ylim] = outputLimits(tform, lS.XWorldLimits, lS.YWorldLimits);
            [xlim2, ylim2]  = outputLimits(affine2d([1 0 0; 0 1 0; 0 0 1]), app.rS.XWorldLimits, app.rS.YWorldLimits);
            xlim(1) = min(xlim(1), xlim2(1));
            xlim(2) = max(xlim(2), xlim2(2));
            ylim(1) = min(ylim(1), ylim2(1));
            ylim(2) = max(ylim(2), ylim2(2));
            xlim= round(xlim);
            ylim = round(ylim);
            sz = [xlim(2)-xlim(1), ylim(2)-ylim(1)];
            waitbar(0.5, app.WB, 'Loading');
            app.panim = imref2d(sz, xlim, ylim);
            [app.ImageR, app.rS] = imwarp(app.ImageR, app.rS, affine2d([1 0 0; 0 1 0; 0 0 1]), 'OutputView', app.panim);
            app.lW = imwarp(app.ImageL, lS, tform, 'OutputView', app.panim);
            waitbar(0., app.WB, 'Loading');
            blender = vision.AlphaBlender('Operation', 'Binary mask', 'MaskSource', 'Input port');
            
            mask = app.ImageL(:, :, 1)+ app.ImageL(:, :, 2) + app.ImageL(:, :, 3);
            mask(mask(:, :)>0) = 1;
            mask = double(mask);
            mask = imerode(mask, strel('disk', 2));
            mask = imwarp(mask, tform, 'OutputView', app.panim);
            app.panorama = blender(app.ImageR, app.lW, mask);
            waitbar(0.6, app.WB, 'Loading');
            %imshow(lW);
            fh = figure;
            axs = axes('Parent', fh, 'Position', [0 0 1.0 0.95]);
            imshow(app.panorama, 'Parent', axs);
            title('290', 'Parent', axs);
            
            %figure;
            amask = app.panorama(:, :, 1)+ app.panorama(:, :, 2) + app.panorama(:, :, 3);
            amask(amask(:, :)==0)=0;
            amask(amask(:, :)>0)=1;
            amask=double(amask);
            
            app.tform = tform;
            %imshow(mask);
            %imwrite(panorama, 'pan.png', 'Alpha', amask);
            
            %whos mask
            %save('mask.mat', 'mask');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            blender = vision.AlphaBlender('Operation', 'Binary mask', 'MaskSource', 'Input port');
            mask = app.ImageL(:, :, 1)+ app.ImageL(:, :, 2) + app.ImageL(:, :, 3);
            mask(mask(:, :)>0) = 1;
            mask = double(mask);
            mask = imerode(mask, strel('disk', 2));
            mask = imwarp(mask, app.tform, 'OutputView', app.panim);
            app.panorama = blender(app.ImageR, app.lW, mask);
            waitbar(0.6, app.WB, 'Loading');
            %imshow(lW);
            fh = figure;
            axs = axes('Parent', fh, 'Position', [0 0 1.0 0.95]);
            imshow(app.panorama, 'Parent', axs);
            title('290', 'Parent', axs);
            
            %figure;
            amask = app.panorama(:, :, 1)+ app.panorama(:, :, 2) + app.panorama(:, :, 3);
            amask(amask(:, :)==0)=0;
            amask(amask(:, :)>0)=1;
            amask=double(amask);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.ImageFileL = uieditfield(app.UIFigure, 'text');
            app.ImageFileL.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField_2Label = uilabel(app.UIFigure);
            app.NameEditField_2Label.Text = 'Name';
            app.ImageFileR = uieditfield(app.UIFigure, 'text');
            app.ImageFileR.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartPushed, true);
            app.Start.Text = 'Button1';
            app.UIAxesL = uiaxes(app.UIFigure);
            title(app.UIAxesL, 'Title')
            xlabel(app.UIAxesL, 'X')
            ylabel(app.UIAxesL, 'Y')
            app.UIAxesR = uiaxes(app.UIFigure);
            title(app.UIAxesR, 'Title')
            xlabel(app.UIAxesR, 'X')
            ylabel(app.UIAxesR, 'Y')
            app.FramesLabel = uilabel(app.UIFigure);
            app.FramesLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.Limits = [1 99];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FramesSlider.Value = 1;
            app.Increment = uibutton(app.UIFigure, 'push');
            app.Increment.ButtonPushedFcn = createCallbackFcn(app, @IncrementPushed, true);
            app.Increment.Text = 'Increment';
            app.DisabledButton = uibutton(app.UIFigure, 'push');
            app.DisabledButton.ButtonPushedFcn = createCallbackFcn(app, @DisabledButtonPushed, true);
            app.DisabledButton.Visible = 'off';
            app.DisabledButton.Text = 'Disabled';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Visible = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end